classdef HX711 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatlabArduinoHX711UIFigure     matlab.ui.Figure
        PressureGauge                  matlab.ui.control.SemicircularGauge
        PressureGaugeLabel             matlab.ui.control.Label
        kPaLabel                       matlab.ui.control.Label
        Pressure                       matlab.ui.control.NumericEditField
        PressureLabel                  matlab.ui.control.Label
        CleanPanel                     matlab.ui.container.Panel
        Clean                          matlab.ui.control.Button
        Rate                           matlab.ui.control.NumericEditField
        SamplingRLabel                 matlab.ui.control.Label
        measure3                       matlab.ui.control.EditField
        measure2                       matlab.ui.control.EditField
        measure                        matlab.ui.control.EditField
        DataEditField                  matlab.ui.control.NumericEditField
        DataEditFieldLabel             matlab.ui.control.Label
        TimeEdit                       matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        ForceEdit                      matlab.ui.control.NumericEditField
        ForceEditFieldLabel            matlab.ui.control.Label
        ContinuosDataAcquisitionPanel  matlab.ui.container.Panel
        Axes1                          matlab.ui.control.UIAxes
        GlobalSettingsPanel            matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ConnectionTab                  matlab.ui.container.Tab
        DataEdit_2                     matlab.ui.control.EditField
        ValveExhaustPinLabel           matlab.ui.control.Label
        ValveFillEdit                  matlab.ui.control.EditField
        ValveFIllPinLabel              matlab.ui.control.Label
        PressureEdit                   matlab.ui.control.EditField
        PressurePinLabel               matlab.ui.control.Label
        DataEdit                       matlab.ui.control.EditField
        DataPinEditFieldLabel          matlab.ui.control.Label
        BoardEdit                      matlab.ui.control.DropDown
        ArduinoDropDownLabel           matlab.ui.control.Label
        ClockEdit                      matlab.ui.control.EditField
        ClockPinEditFieldLabel         matlab.ui.control.Label
        SerialEdit                     matlab.ui.control.EditField
        SerialportEditFieldLabel       matlab.ui.control.Label
        DataAcquisitionTab             matlab.ui.container.Tab
        Nsamples                       matlab.ui.control.Spinner
        SamplesLabel                   matlab.ui.control.Label
        PressureDeadband               matlab.ui.control.Spinner
        DeadbandkPaLabel               matlab.ui.control.Label
        DesiredPressure                matlab.ui.control.Spinner
        SetpointkPaSpinnerLabel        matlab.ui.control.Label
        EnablePressureControl          matlab.ui.control.CheckBox
        Add_time                       matlab.ui.control.Spinner
        SamplingRateSpinnerLabel       matlab.ui.control.Label
        SessionV                       matlab.ui.control.Spinner
        SessionTimeSpinnerLabel        matlab.ui.control.Label
        SetSession                     matlab.ui.control.CheckBox
        Unit                           matlab.ui.control.DropDown
        ForceDropDownLabel             matlab.ui.control.Label
        SaveDataTab                    matlab.ui.container.Tab
        RunSpinner                     matlab.ui.control.Spinner
        CollectionRunLabel             matlab.ui.control.Label
        SeriesSpinner                  matlab.ui.control.Spinner
        DataSeriesSpinnerLabel         matlab.ui.control.Label
        SaveFolder                     matlab.ui.control.EditField
        FolderLabel                    matlab.ui.control.Label
        Reset                          matlab.ui.control.CheckBox
        MetadataTab                    matlab.ui.container.Tab
        LoadCellAngle                  matlab.ui.control.NumericEditField
        LoadcellangledegLabel          matlab.ui.control.Label
        KneeAngle                      matlab.ui.control.NumericEditField
        KneeAngledegLabel              matlab.ui.control.Label
        CalibrationResultPanel         matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        ValueTab                       matlab.ui.container.Tab
        measure4                       matlab.ui.control.EditField
        Raw                            matlab.ui.control.NumericEditField
        RawreadingLabel                matlab.ui.control.Label
        StdDisp                        matlab.ui.control.NumericEditField
        StdDeviationgLabel             matlab.ui.control.Label
        AverageDisp                    matlab.ui.control.NumericEditField
        AveragegLabel                  matlab.ui.control.Label
        ScaleDisp                      matlab.ui.control.NumericEditField
        ScalefactorLabel               matlab.ui.control.Label
        TareDisp                       matlab.ui.control.NumericEditField
        TareLabel                      matlab.ui.control.Label
        Gauge                          matlab.ui.control.LinearGauge
        SettingsTab                    matlab.ui.container.Tab
        UnitLabel_2                    matlab.ui.control.Label
        UnitLabel                      matlab.ui.control.Label
        UnitCal2                       matlab.ui.control.DropDown
        MaxLoad                        matlab.ui.control.NumericEditField
        MaxLoadLabel                   matlab.ui.control.Label
        UnitCal                        matlab.ui.control.DropDown
        Known                          matlab.ui.control.NumericEditField
        KnownWeightLabel               matlab.ui.control.Label
        n                              matlab.ui.control.NumericEditField
        NumreadingsLabel               matlab.ui.control.Label
        LicenseTab                     matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        KnownLCCalTab                  matlab.ui.container.Tab
        KnownTare                      matlab.ui.control.NumericEditField
        ZerooffsettareEditFieldLabel   matlab.ui.control.Label
        PressureCalTab                 matlab.ui.container.Tab
        Axes2                          matlab.ui.control.UIAxes
        CalibrationPanel               matlab.ui.container.Panel
        RawRead                        matlab.ui.control.Button
        CalibrationButton              matlab.ui.control.Button
        ScaleFactorButton              matlab.ui.control.Button
        TareButton                     matlab.ui.control.Button
        ArduinoHX711Panel              matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        GetData                        matlab.ui.control.Button
        Connect                        matlab.ui.control.Button
        StatusPanel                    matlab.ui.container.Panel
        Cyan                           matlab.ui.control.Lamp
        DataAcquisitionLabel           matlab.ui.control.Label
        Yellow                         matlab.ui.control.Lamp
        InpauseLabel                   matlab.ui.control.Label
        Red                            matlab.ui.control.Lamp
        NotConnectedLabel              matlab.ui.control.Label
        Green                          matlab.ui.control.Lamp
        ConnectedLabel                 matlab.ui.control.Label
        Message                        matlab.ui.control.EditField
        MessageEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        a              % Arduino object
        serial         % Serial port
        board          % Arduino board
        data           % Data pin
        clock          % Clock pin
        pressurePin    % Pressure pin
        HX711_obj      % HX711 class object
        tare = 0
        scale = 1
        g = 9.80665
        check_connection = false
        v=1
        t=0
        session_time = Inf
        known_weight = 0
        Max_Load
        last_time1 = 0
        last_time2 = 0
        last_data = 0
        file_name1
        file_name2
        rst_time = 0
        get_true = false
        basePath = fullfile('GitHub','Bipedal_Robot','Testing_Data','2026_06_Festo','Flx_20mm'); % adjust root if needed
        pressureA = 155.61;   % default slope (kPa/V) – matches your current hard-coded line
        pressureB = -126.99;  % default intercept (kPa)
        valvePin1   % e.g. D11
        valvePin2   % e.g. D6
    end
    
    properties (Access = public)
        i = 1           % Indece of force array
        time = []       % Array of time
        force = []      % Array of force
        r = 0
        k1 = 1
        k2 = 1
        Series = 1   % test series index
        Run    = 0   % run number 0–99
    end
    
    methods (Access = private)
        
        function Xaxis(app)
            if app.time(app.i) > 100*app.v || app.t == 1
                app.v = app.v + 1;
                app.Axes1.XLim = [0 100*app.v];
                app.Axes1.XTick = [0 10*app.v 20*app.v 30*app.v 40*app.v 50*app.v...
                    60*app.v 70*app.v 80*app.v 90*app.v 100*app.v];
            end
        end
        
        function Xaxis2(app)
            app.Axes1.XLim = [0 100];
            app.Axes1.XTick = [0 10 20 30 40 50 60 70 80 90 100];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Connect
        function ConnectButtonPushed(app, event)
            app.Message.Value = 'Please wait...';
            app.serial = app.SerialEdit.Value;
            app.board = app.BoardEdit.Value;
            app.data = app.DataEdit.Value;
            app.clock = app.ClockEdit.Value;
            app.pressurePin = app.PressureEdit.Value;
            app.a=arduino(app.serial,app.board,'libraries',{'basicHX711/basic_HX711'});
            app.HX711_obj = addon(app.a,'basicHX711/basic_HX711',{app.data,app.clock});
            app.pressureV = readVoltage(app.a,app.pressurePin);
            app.check_connection = true;
            app.Red.Color = 'white';
            app.Green.Color = 'green';
            app.Yellow.Color = 'yellow';
            app.Message.Value = 'Done.';
        end

        % Button pushed function: GetData
        function GetDataButtonPushed(app, event)
            %------------------------------------------------------------------
            if ~ app.check_connection
                app.Message.Value = 'Error: You are not connected yet.';
                return;
            end
            if app.tare == 0 || app.scale == 1
                app.Message.Value = 'Error: You must calibrate your loadcell first.';
                return;
            end
            %------------------------------------------------------------------
            app.r = 0;
            app.t = 0;
            app.measure.Value = app.Unit.Value;
            if app.i == 1
                app.time(app.i) = 0;
            else
                app.time(app.i) = app.time(app.i-1);
            end
            %------------------------------------------------------------------
            if app.SetSession.Value == 1
                app.session_time = app.SessionV.Value*60 + app.time(app.i);
            else
                app.session_time = Inf;
            end
            % ***************************************************
            switch app.Unit.Value
                case '[ kg ]'
                    app.measure.Value = 'kg';
                case '[ N ]'
                    app.measure.Value = 'N';
                case '[ g ]'
                    app.measure.Value = 'g';
                case '[ kN ]'
                    app.measure.Value = 'kN';
            end
            % ***************************************************
            %------------------------------------------------------------------
            app.Message.Value='Data acquisition...';
            app.Cyan.Color = 'cyan';
            app.Yellow.Color = 'white';
            app.get_true = true;
            while (app.r==0) && (app.time(app.i) < app.session_time)
                tic;
                x = (read_HX711(app.HX711_obj) - app.tare)/app.scale;
                p = app.pressureV*155.61-126.99;
                if app.MaxLoad.Value ~= 0
                    switch app.UnitCal2.Value
                        case '[ g ]'
                            app.Max_Load = app.MaxLoad.Value;
                        case '[ kg ]'
                            app.Max_Load = app.MaxLoad.Value*1000;
                        case '[ N ]'
                            app.Max_Load = app.MaxLoad.Value/app.g*1000;
                        case '[ kN ]'
                            app.Max_Load = app.MaxLoad.Value/app.g*1000000;
                    end
                    app.Gauge.Value = abs((x/app.Max_Load))*100;
                end
                % ***************************************************
                switch app.Unit.Value
                    case '[ kg ]'
                        x = x/1000;
                    case '[ N ]'
                        x = x*app.g/1000;
                    case '[ kN ]'
                        x = x*app.g/1000000;
                end
                % ***************************************************
                app.force(app.i) = x;
                app.pressure(app.i) = p;
                plot(app.Axes1,app.time,app.force);
                app.Pressure.Value = app.pressure(app.i);
                app.PressureGauge.Value = app.pressure(app.i);
                app.ForceEdit.Value = app.force(app.i);
                app.TimeEdit.Value = app.time(app.i) - app.rst_time;
                app.DataEditField.Value = app.i - app.last_data;
                % ---------------------------------------------------
                pause(9/100 + app.Add_time.Value - 0.1);
                Xaxis(app);
                app.i = app.i + 1;
                app.Rate.Value = toc;
                app.time(app.i) = toc + app.time(app.i-1);
            end
            app.Cyan.Color = 'white';
            app.Yellow.Color = 'yellow';
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.r = 1;
            app.Message.Value = 'In pause';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %------------------------------------------------------------------
            if ~ app.check_connection
                app.Message.Value = 'Error: You are not connected yet.';
                return;
            end
            if ~ app.get_true
                app.Message.Value = 'Error: You have not got any data yet.';
                return;
            end
            %------------------------------------------------------------------
            if app.Reset.Value == 1 && app.k1 > 1
                last1 = app.last_time1;
            else
                last1 = 0;
            end
            if app.Reset.Value == 1 && app.k2 > 1
                last2 = app.last_time2;
            else
                last2 = 0;
            end
            switch app.Extension.Value
                case '.txt'
                    if strcmp(app.file_name1,app.Name.Value)
                        app.Message.Value = ['Error, you have already used ',app.Name.Value];
                        return;
                    end
                    app.file_name1 = app.Name.Value;
                    file_name = [app.file_name1,'.txt'];
                    fileID = fopen(file_name,'wt');
                    force_title = ['Force ',app.Unit.Value];
                    fprintf(fileID,'%11s %15s\n','Time [ s ]',force_title);
                    for j = app.k1:1:(app.i-1)
                        fprintf(fileID,'%8.2f %15.2f\n',(app.time(j)-last1),app.force(j));
                    end
                    app.k1 = app.i - 1;
                    app.last_time1 = app.time(app.k1);
                    app.Message.Value='Saved.';
                    fclose(fileID);
                case '.xls'
                    if strcmp(app.file_name2,app.Name.Value)
                        app.Message.Value = ['Error: you have already used ',app.Name.Value];
                        return;
                    end
                    app.file_name2 = app.Name.Value;
                    file_name = [app.file_name2,'.xlsx'];
                    A = cell(2*app.i,2);
                    A{1,1} = 'Time';
                    A{1,2} = 'Force';
                    h = 1;
                    for j = app.k2:1:(app.i-1)
                        A{h + 1,1} = round((app.time(j)-last2),2);
                        A{h + 1,2} = round(app.force(j),2);
                        h = h + 1;
                    end
                    xlswrite(file_name,A);
                    app.k2 = app.i - 1;
                    app.last_time2 = app.time(app.k2);
                    app.Message.Value='Saved.';
            end
        end

        % Button pushed function: TareButton
        function TareButtonPushed(app, event)
            if ~ app.check_connection
                app.Message.Value = 'Error: You are not connected yet.';
                return;
            end
            if app.n.Value == 0
                app.Message.Value = 'Error: set number of readings.';
                return;
            end
            app.Message.Value = 'Please wait...';
            app.Cyan.Color = 'cyan';
            app.Yellow.Color = 'white';
            z = app.n.Value;
            x = 1:1:z;
            for j=1:1:z
                x(j) = read_HX711(app.HX711_obj);
                pause(1/1000);
            end
            app.tare = sum(x)./z;
            app.TareDisp.Value = app.tare;
            app.Message.Value = 'Tare phase is completed.';
            app.Cyan.Color = 'white';
            app.Yellow.Color = 'yellow';
        end

        % Button pushed function: ScaleFactorButton
        function ScaleFactorButtonPushed(app, event)
            if ~ app.check_connection
                app.Message.Value = 'Error: You are not connected yet.';
                return;
            end
            if app.tare == 0
                app.Message.Value = 'Error: perform tare phase first.';
                return;
            end
            switch app.UnitCal.Value
                case '[ g ]'
                    app.known_weight = app.Known.Value;
                case '[ kg ]'
                    app.known_weight = app.Known.Value*1000;
                case '[ N ]'
                    app.known_weight = app.Known.Value/app.g*1000;
                case '[ kN ]'
                    app.known_weight = app.Known.Value/app.g*1000000;
            end
            if app.MaxLoad.Value ~= 0
                switch app.UnitCal2.Value
                    case '[ g ]'
                        app.Max_Load = app.MaxLoad.Value;
                    case '[ kg ]'
                        app.Max_Load = app.MaxLoad.Value*1000;
                    case '[ N ]'
                        app.Max_Load = app.MaxLoad.Value/app.g*1000;
                    case '[ kN ]'
                        app.Max_Load = app.MaxLoad.Value/app.g*1000000;
                end
                app.Gauge.Value = (app.known_weight/app.Max_Load)*100;
            end
            if app.known_weight == 0
                app.Message.Value = 'Error: check the known weight for calibration.';
                return;
            end
            app.Message.Value = 'Please wait...';
            app.Cyan.Color = 'cyan';
            app.Yellow.Color = 'white';
            z = app.n.Value;
            x = 1:1:z;
            for j=1:1:z
                x(j) = read_HX711(app.HX711_obj);
                pause(1/1000);
            end
            app.scale = (sum(x)./z - app.tare)/app.known_weight;
            app.ScaleDisp.Value = app.scale;
            app.Message.Value = 'Scale factor is determined.';
            app.Cyan.Color = 'white';
            app.Yellow.Color = 'yellow';
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            if ~ app.check_connection
                app.Message.Value = 'Error: You are not connected yet.';
                return;
            end
            if app.tare == 0
                app.Message.Value = 'Error: perform tare phase first.';
                return;
            elseif app.scale == 1
                app.Message.Value = 'Error: calculate scale factor first.';
                return;
            end
            app.Message.Value = 'Please wait...';
            app.Cyan.Color = 'cyan';
            app.Yellow.Color = 'white';
            switch app.UnitCal.Value
                case '[ g ]'
                    app.known_weight = app.Known.Value;
                case '[ kg ]'
                    app.known_weight = app.Known.Value*1000;
                case '[ N ]'
                    app.known_weight = app.Known.Value/app.g*1000;
                case '[ kN ]'
                    app.known_weight = app.Known.Value/app.g*1000000;
            end
            if app.MaxLoad.Value ~= 0
                switch app.UnitCal2.Value
                    case '[ g ]'
                        app.Max_Load = app.MaxLoad.Value;
                    case '[ kg ]'
                        app.Max_Load = app.MaxLoad.Value*1000;
                    case '[ N ]'
                        app.Max_Load = app.MaxLoad.Value/app.g*1000;
                    case '[ kN ]'
                        app.Max_Load = app.MaxLoad.Value/app.g*1000000;
                end
                app.Gauge.Value = (app.known_weight/app.Max_Load)*100;
            end
            z = app.n.Value;
            x = 1:1:z;
            for j=1:1:z
                x(j) = read_HX711(app.HX711_obj);
                pause(1/1000);
            end
            x = (x - app.tare)./app.scale;
            M = mean(x);
            S = std(x);
            app.AverageDisp.Value = M;
            app.StdDisp.Value = S;
            w = linspace(M-4*S,M+4*S,5000);
            y = (S*sqrt(2*pi))^-1*exp(-((w-M).^2)./(2*S^2));
            app.Axes2.LineWidth = 2;
            plot(app.Axes2,w,y,[app.known_weight app.known_weight],[0 max(y)*1.1],[M-2*S M-2*S],[0 max(y)]/2,[M+2*S M+2*S],[0 max(y)]/2);
            app.Message.Value = 'Calibration phase is completed.';
            app.Cyan.Color = 'white';
            app.Yellow.Color = 'yellow';
        end

        % Button pushed function: RawRead
        function RawReadButtonPushed(app, event)
            if ~ app.check_connection
                app.Message.Value = 'Error: You are not connected yet.';
                return;
            end
            if app.tare == 0
                app.Message.Value = 'Error: perform tare phase first.';
                return;
            elseif app.scale == 1
                app.Message.Value = 'Error: calculate scale factor first.';
                return;
            end
            app.Message.Value = 'Done';
            % ***************************************************
            switch app.Unit.Value
                case '[ kg ]'
                    app.measure4.Value = 'kg';
                case '[ N ]'
                    app.measure4.Value = 'N';
                case '[ g ]'
                    app.measure4.Value = 'g';
                case '[ kN ]'
                    app.measure4.Value = 'kN';
            end
            % ***************************************************
            %------------------------------------------------------------------
            app.Message.Value='Data acquisition';
            app.Cyan.Color = 'cyan';
            app.Yellow.Color = 'white';
            x = (read_HX711(app.HX711_obj) - app.tare)/app.scale;
            if app.MaxLoad.Value ~= 0
                switch app.UnitCal2.Value
                    case '[ g ]'
                        app.Max_Load = app.MaxLoad.Value;
                    case '[ kg ]'
                        app.Max_Load = app.MaxLoad.Value*1000;
                    case '[ N ]'
                        app.Max_Load = app.MaxLoad.Value/app.g*1000;
                    case '[ kN ]'
                        app.Max_Load = app.MaxLoad.Value/app.g*1000000;
                end
                app.Gauge.Value = abs(x/app.Max_Load)*100;
            end
            % ***************************************************
            switch app.Unit.Value
                case '[ kg ]'
                    x = x/1000;
                case '[ N ]'
                    x = x*app.g/1000;
                case '[ kN ]'
                    x = x*app.g/1000000;
            end
            % ***************************************************
            app.Raw.Value = x;
            app.Cyan.Color = 'white';
            app.Yellow.Color = 'yellow';
        end

        % Button pushed function: Clean
        function CleanButtonPushed(app, event)
            if ~ app.check_connection
                app.Message.Value = 'Error: You are not connected yet.';
                return;
            end
            cla(app.Axes1);
            cla(app.Axes2);
            for j = 1:1:app.i
                app.time(j) = 0;
                app.force(j) = 0;
            end
            app.v = 1;
            app.t = 1;
            Xaxis2(app);
            app.last_time1 = 0;
            app.last_time2 = 0;
            app.rst_time = app.time(app.i);
            app.k1 = app.i;
            app.k2 = app.i;
            app.last_data = app.i;
            app.DataEditField.Value = 0;
            app.PressureGauge.Value = 0;
            app.ForceEdit.Value = 0;
            app.TimeEdit.Value = 0;
            app.AverageDisp.Value = 0;
            app.StdDisp.Value = 0;
            app.Message.Value = 'All cleaned up.';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatlabArduinoHX711UIFigure = uifigure('Visible', 'off');
            app.MatlabArduinoHX711UIFigure.Name = 'Matlab - Arduino - HX711';
            app.MessageEditFieldLabel = uilabel(app.MatlabArduinoHX711UIFigure);
            app.MessageEditFieldLabel.Text = 'Message';
            app.Message = uieditfield(app.MatlabArduinoHX711UIFigure, 'text');
            app.Message.Editable = 'off';
            app.StatusPanel = uipanel(app.MatlabArduinoHX711UIFigure);
            app.StatusPanel.Title = 'Status';
            app.ConnectedLabel = uilabel(app.StatusPanel);
            app.ConnectedLabel.Text = 'Connected       ';
            app.Green = uilamp(app.StatusPanel);
            app.NotConnectedLabel = uilabel(app.StatusPanel);
            app.NotConnectedLabel.Text = 'Not Connected            ';
            app.Red = uilamp(app.StatusPanel);
            app.InpauseLabel = uilabel(app.StatusPanel);
            app.InpauseLabel.Text = 'In pause  ';
            app.Yellow = uilamp(app.StatusPanel);
            app.DataAcquisitionLabel = uilabel(app.StatusPanel);
            app.DataAcquisitionLabel.Text = 'Data Acquisition             ';
            app.Cyan = uilamp(app.StatusPanel);
            app.ArduinoHX711Panel = uipanel(app.MatlabArduinoHX711UIFigure);
            app.ArduinoHX711Panel.Title = 'Arduino - HX711';
            app.Connect = uibutton(app.ArduinoHX711Panel, 'push');
            app.Connect.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.Connect.Text = 'Connect';
            app.GetData = uibutton(app.ArduinoHX711Panel, 'push');
            app.GetData.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetData.Text = 'Get Data';
            app.PauseButton = uibutton(app.ArduinoHX711Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.SaveButton = uibutton(app.ArduinoHX711Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ';
            app.CalibrationPanel = uipanel(app.MatlabArduinoHX711UIFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.TareButton = uibutton(app.CalibrationPanel, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareButtonPushed, true);
            app.TareButton.Text = 'Tare';
            app.ScaleFactorButton = uibutton(app.CalibrationPanel, 'push');
            app.ScaleFactorButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleFactorButtonPushed, true);
            app.ScaleFactorButton.Text = 'Scale Factor';
            app.CalibrationButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Text = 'Calibration';
            app.RawRead = uibutton(app.CalibrationPanel, 'push');
            app.RawRead.ButtonPushedFcn = createCallbackFcn(app, @RawReadButtonPushed, true);
            app.RawRead.Text = 'Get Data';
            app.CalibrationResultPanel = uipanel(app.MatlabArduinoHX711UIFigure);
            app.CalibrationResultPanel.Title = 'Calibration Result';
            app.Axes2 = uiaxes(app.CalibrationResultPanel);
            app.Axes2.ZLim = [0 1];
            app.Axes2.ZTick = [0 0.5 1];
            app.TabGroup2 = uitabgroup(app.CalibrationResultPanel);
            app.ValueTab = uitab(app.TabGroup2);
            app.ValueTab.Title = 'Value';
            app.Gauge = uigauge(app.ValueTab, 'linear');
            app.TareLabel = uilabel(app.ValueTab);
            app.TareLabel.Text = 'Tare';
            app.TareDisp = uieditfield(app.ValueTab, 'numeric');
            app.TareDisp.ValueDisplayFormat = '%.0f';
            app.TareDisp.Editable = 'off';
            app.ScalefactorLabel = uilabel(app.ValueTab);
            app.ScalefactorLabel.Text = 'Scale factor';
            app.ScaleDisp = uieditfield(app.ValueTab, 'numeric');
            app.ScaleDisp.ValueDisplayFormat = '%.2f';
            app.ScaleDisp.Editable = 'off';
            app.AveragegLabel = uilabel(app.ValueTab);
            app.AveragegLabel.Text = 'Average [g]';
            app.AverageDisp = uieditfield(app.ValueTab, 'numeric');
            app.AverageDisp.ValueDisplayFormat = '%.2f';
            app.AverageDisp.Editable = 'off';
            app.StdDeviationgLabel = uilabel(app.ValueTab);
            app.StdDeviationgLabel.Text = 'Std Deviation [g]';
            app.StdDisp = uieditfield(app.ValueTab, 'numeric');
            app.StdDisp.ValueDisplayFormat = '%.2f';
            app.StdDisp.Editable = 'off';
            app.RawreadingLabel = uilabel(app.ValueTab);
            app.RawreadingLabel.Text = 'Raw reading';
            app.Raw = uieditfield(app.ValueTab, 'numeric');
            app.Raw.ValueDisplayFormat = '%.2f';
            app.Raw.Editable = 'off';
            app.measure4 = uieditfield(app.ValueTab, 'text');
            app.measure4.Editable = 'off';
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.NumreadingsLabel = uilabel(app.SettingsTab);
            app.NumreadingsLabel.Text = 'Num. readings';
            app.n = uieditfield(app.SettingsTab, 'numeric');
            app.n.ValueDisplayFormat = '%.0f';
            app.KnownWeightLabel = uilabel(app.SettingsTab);
            app.KnownWeightLabel.Text = 'Known Weight';
            app.Known = uieditfield(app.SettingsTab, 'numeric');
            app.Known.Limits = [0 Inf];
            app.Known.ValueDisplayFormat = '%.3f';
            app.UnitCal = uidropdown(app.SettingsTab);
            app.UnitCal.Items = {'[ g ]', '[ kg ]', '[ N ]', '[ kN ]'};
            app.UnitCal.Value = '[ N ]';
            app.MaxLoadLabel = uilabel(app.SettingsTab);
            app.MaxLoadLabel.Text = 'Max Load';
            app.MaxLoad = uieditfield(app.SettingsTab, 'numeric');
            app.MaxLoad.Limits = [0 Inf];
            app.MaxLoad.ValueDisplayFormat = '%.2f';
            app.MaxLoad.Value = 100;
            app.UnitCal2 = uidropdown(app.SettingsTab);
            app.UnitCal2.Items = {'[ g ]', '[ kg ]', '[ N ]', '[ kN ]'};
            app.UnitCal2.Value = '[ kg ]';
            app.UnitLabel = uilabel(app.SettingsTab);
            app.UnitLabel.Text = 'Unit';
            app.UnitLabel_2 = uilabel(app.SettingsTab);
            app.UnitLabel_2.Text = 'Unit';
            app.LicenseTab = uitab(app.TabGroup2);
            app.LicenseTab.Title = '*License*';
            app.TextArea = uitextarea(app.LicenseTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Copyright 2018, Nicholas Giacoboni'; ''; 'Redistribution and use in source and binary forms, with or without modification, are permitted provided that the following conditions are met:'; ''; ' 1. Redistributions of source code must retain the above copyright notice, this list of conditions and the following disclaimer.'; ''; ' 2. Redistributions in binary form must reproduce the above copyright'; 'notice, this list of conditions and the following disclaimer in the documentation and/or other materials provided with the distribution.'; ''; 'THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS" AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE IMPLIED'; 'WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE DISCLAIMED.'; 'IN NO EVENT SHALL THE COPYRIGHT HOLDER OR CONTRIBUTORS BE LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY, OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.'};
            app.KnownLCCalTab = uitab(app.TabGroup2);
            app.KnownLCCalTab.Title = 'Known LC Cal';
            app.ZerooffsettareEditFieldLabel = uilabel(app.KnownLCCalTab);
            app.ZerooffsettareEditFieldLabel.Text = 'Zero offset / tare';
            app.KnownTare = uieditfield(app.KnownLCCalTab, 'numeric');
            app.PressureCalTab = uitab(app.TabGroup2);
            app.PressureCalTab.Title = 'Pressure Cal';
            app.GlobalSettingsPanel = uipanel(app.MatlabArduinoHX711UIFigure);
            app.GlobalSettingsPanel.Title = 'Global Settings';
            app.TabGroup = uitabgroup(app.GlobalSettingsPanel);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.SerialportEditFieldLabel = uilabel(app.ConnectionTab);
            app.SerialportEditFieldLabel.Text = 'Serial port';
            app.SerialEdit = uieditfield(app.ConnectionTab, 'text');
            app.SerialEdit.Value = 'Com4';
            app.ClockPinEditFieldLabel = uilabel(app.ConnectionTab);
            app.ClockPinEditFieldLabel.Text = 'Clock Pin';
            app.ClockEdit = uieditfield(app.ConnectionTab, 'text');
            app.ClockEdit.Value = 'D2';
            app.ArduinoDropDownLabel = uilabel(app.ConnectionTab);
            app.ArduinoDropDownLabel.Text = 'Arduino ';
            app.BoardEdit = uidropdown(app.ConnectionTab);
            app.BoardEdit.Items = {'Uno', 'Mega2560'};
            app.BoardEdit.Value = 'Uno';
            app.DataPinEditFieldLabel = uilabel(app.ConnectionTab);
            app.DataPinEditFieldLabel.Text = 'Data Pin';
            app.DataEdit = uieditfield(app.ConnectionTab, 'text');
            app.DataEdit.Value = 'D3';
            app.PressurePinLabel = uilabel(app.ConnectionTab);
            app.PressurePinLabel.Text = 'Pressure Pin';
            app.PressureEdit = uieditfield(app.ConnectionTab, 'text');
            app.PressureEdit.Value = 'A0';
            app.ValveFIllPinLabel = uilabel(app.ConnectionTab);
            app.ValveFIllPinLabel.Text = 'Valve FIll Pin';
            app.ValveFillEdit = uieditfield(app.ConnectionTab, 'text');
            app.ValveFillEdit.Value = 'D11';
            app.ValveExhaustPinLabel = uilabel(app.ConnectionTab);
            app.ValveExhaustPinLabel.Text = 'Valve Exhaust Pin';
            app.DataEdit_2 = uieditfield(app.ConnectionTab, 'text');
            app.DataEdit_2.Value = 'D6';
            app.DataAcquisitionTab = uitab(app.TabGroup);
            app.DataAcquisitionTab.Title = 'Data Acquisition';
            app.ForceDropDownLabel = uilabel(app.DataAcquisitionTab);
            app.ForceDropDownLabel.Text = 'Force';
            app.Unit = uidropdown(app.DataAcquisitionTab);
            app.Unit.Items = {'[ g ]', '[ kg ]', '[ N ]', '[ kN ]'};
            app.Unit.Value = '[ N ]';
            app.SetSession = uicheckbox(app.DataAcquisitionTab);
            app.SetSession.Text = 'Set Session Time [min]';
            app.SessionTimeSpinnerLabel = uilabel(app.DataAcquisitionTab);
            app.SessionTimeSpinnerLabel.Text = 'Session Time';
            app.SessionV = uispinner(app.DataAcquisitionTab);
            app.SessionV.Limits = [0 Inf];
            app.SamplingRateSpinnerLabel = uilabel(app.DataAcquisitionTab);
            app.SamplingRateSpinnerLabel.Text = 'Sampling Rate';
            app.Add_time = uispinner(app.DataAcquisitionTab);
            app.Add_time.Step = 0.1;
            app.Add_time.Limits = [0.1 Inf];
            app.Add_time.ValueDisplayFormat = '%.1f';
            app.Add_time.Value = 0.1;
            app.EnablePressureControl = uicheckbox(app.DataAcquisitionTab);
            app.EnablePressureControl.Text = 'Pressure Control';
            app.EnablePressureControl.Value = true;
            app.SetpointkPaSpinnerLabel = uilabel(app.DataAcquisitionTab);
            app.SetpointkPaSpinnerLabel.Text = 'Setpoint [kPa]';
            app.DesiredPressure = uispinner(app.DataAcquisitionTab);
            app.DesiredPressure.Step = 10;
            app.DesiredPressure.Limits = [0 700];
            app.DeadbandkPaLabel = uilabel(app.DataAcquisitionTab);
            app.DeadbandkPaLabel.Text = 'Deadband [kPa]';
            app.PressureDeadband = uispinner(app.DataAcquisitionTab);
            app.PressureDeadband.Limits = [0 Inf];
            app.PressureDeadband.Value = 10;
            app.SamplesLabel = uilabel(app.DataAcquisitionTab);
            app.SamplesLabel.Text = 'Samples';
            app.Nsamples = uispinner(app.DataAcquisitionTab);
            app.Nsamples.Limits = [1 750];
            app.Nsamples.Value = 750;
            app.SaveDataTab = uitab(app.TabGroup);
            app.SaveDataTab.Title = 'Save Data';
            app.Reset = uicheckbox(app.SaveDataTab);
            app.Reset.Text = 'Reset Time';
            app.FolderLabel = uilabel(app.SaveDataTab);
            app.FolderLabel.Text = 'Folder';
            app.SaveFolder = uieditfield(app.SaveDataTab, 'text');
            app.SaveFolder.Value = 'GitHub\Bipedal_Robot\Testing_Data\2026_06_Festo\Flx_20mm';
            app.DataSeriesSpinnerLabel = uilabel(app.SaveDataTab);
            app.DataSeriesSpinnerLabel.Text = 'Data Series';
            app.SeriesSpinner = uispinner(app.SaveDataTab);
            app.SeriesSpinner.Limits = [0 999];
            app.SeriesSpinner.Value = 1;
            app.CollectionRunLabel = uilabel(app.SaveDataTab);
            app.CollectionRunLabel.Text = 'Collection Run';
            app.RunSpinner = uispinner(app.SaveDataTab);
            app.RunSpinner.Limits = [0 99];
            app.RunSpinner.ValueDisplayFormat = '%02d';
            app.RunSpinner.Value = 1;
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.KneeAngledegLabel = uilabel(app.MetadataTab);
            app.KneeAngledegLabel.Text = 'Knee Angle [deg]';
            app.KneeAngle = uieditfield(app.MetadataTab, 'numeric');
            app.LoadcellangledegLabel = uilabel(app.MetadataTab);
            app.LoadcellangledegLabel.Text = 'Load cell angle [deg]';
            app.LoadCellAngle = uieditfield(app.MetadataTab, 'numeric');
            app.ContinuosDataAcquisitionPanel = uipanel(app.MatlabArduinoHX711UIFigure);
            app.ContinuosDataAcquisitionPanel.Title = 'Continuos Data Acquisition';
            app.Axes1 = uiaxes(app.ContinuosDataAcquisitionPanel);
            xlabel(app.Axes1, 't [s]')
            app.Axes1.XLim = [0 100];
            app.Axes1.ZLim = [0 1];
            app.Axes1.CLim = [0 1];
            app.Axes1.ZTick = [0 0.5 1];
            app.ForceEditFieldLabel = uilabel(app.MatlabArduinoHX711UIFigure);
            app.ForceEditFieldLabel.Text = 'Force';
            app.ForceEdit = uieditfield(app.MatlabArduinoHX711UIFigure, 'numeric');
            app.ForceEdit.ValueDisplayFormat = '%.2f';
            app.ForceEdit.Editable = 'off';
            app.TimeEditFieldLabel = uilabel(app.MatlabArduinoHX711UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEdit = uieditfield(app.MatlabArduinoHX711UIFigure, 'numeric');
            app.TimeEdit.ValueDisplayFormat = '%.1f';
            app.TimeEdit.Editable = 'off';
            app.DataEditFieldLabel = uilabel(app.MatlabArduinoHX711UIFigure);
            app.DataEditFieldLabel.Text = '# Data';
            app.DataEditField = uieditfield(app.MatlabArduinoHX711UIFigure, 'numeric');
            app.DataEditField.ValueDisplayFormat = '%.0f';
            app.DataEditField.Editable = 'off';
            app.measure = uieditfield(app.MatlabArduinoHX711UIFigure, 'text');
            app.measure.Editable = 'off';
            app.measure2 = uieditfield(app.MatlabArduinoHX711UIFigure, 'text');
            app.measure2.Editable = 'off';
            app.measure2.Enable = 'off';
            app.measure2.Value = 's';
            app.measure3 = uieditfield(app.MatlabArduinoHX711UIFigure, 'text');
            app.measure3.Editable = 'off';
            app.measure3.Enable = 'off';
            app.measure3.Value = 's';
            app.SamplingRLabel = uilabel(app.MatlabArduinoHX711UIFigure);
            app.SamplingRLabel.Text = 'Sampling R.';
            app.Rate = uieditfield(app.MatlabArduinoHX711UIFigure, 'numeric');
            app.Rate.ValueDisplayFormat = '%.1f';
            app.Rate.Editable = 'off';
            app.CleanPanel = uipanel(app.MatlabArduinoHX711UIFigure);
            app.CleanPanel.Title = 'Clean';
            app.Clean = uibutton(app.CleanPanel, 'push');
            app.Clean.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.Clean.Text = 'Plot & Data';
            app.PressureLabel = uilabel(app.MatlabArduinoHX711UIFigure);
            app.PressureLabel.Text = 'Pressure';
            app.Pressure = uieditfield(app.MatlabArduinoHX711UIFigure, 'numeric');
            app.Pressure.ValueDisplayFormat = '%.2f';
            app.Pressure.Editable = 'off';
            app.kPaLabel = uilabel(app.MatlabArduinoHX711UIFigure);
            app.kPaLabel.Text = 'kPa';
            app.PressureGaugeLabel = uilabel(app.MatlabArduinoHX711UIFigure);
            app.PressureGaugeLabel.Text = 'Pressure Gauge';
            app.PressureGauge = uigauge(app.MatlabArduinoHX711UIFigure, 'semicircular');
            app.PressureGauge.Limits = [0 700];
            % Show the figure after all components are created
            app.MatlabArduinoHX711UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HX711

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatlabArduinoHX711UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatlabArduinoHX711UIFigure)
        end
    end
end